classdef graph_3d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        MatrixInputLabel     matlab.ui.control.Label
        StartEditFieldLabel  matlab.ui.control.Label
        StartEditField       matlab.ui.control.EditField
        StepEditFieldLabel   matlab.ui.control.Label
        StepEditField        matlab.ui.control.EditField
        EndEditFieldLabel    matlab.ui.control.Label
        EndEditField         matlab.ui.control.EditField
        FcnEditFieldLabel    matlab.ui.control.Label
        FcnEditField         matlab.ui.control.EditField
        PlotButton           matlab.ui.control.Button
        ContourCheckBox      matlab.ui.control.CheckBox
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x_start = str2double(app.StartEditField.Value);
            x_step = str2double(app.StepEditField.Value);
            x_end = str2double(app.EndEditField.Value);
            
            %roughly check matrix given condition
%             if (((x_step < 0) && (x_start < x_end)) ||...
%                 ((x_step > 0) && (x_start > x_end)))
            if ((((x_end - x_start) / x_step) <= 0) ||...
                (x_step == 0))
                msgbox("Please double-check the matrix input...")
            else
                
                try 
                    x = x_start:x_step:x_end;
                    y = x';
                    fcn_v = str2func(['@(x,y)' vectorize(app.FcnEditField.Value)]);  
                    disp(fcn_v)
                    
                    if app.ContourCheckBox.Value == true
                        contour(app.UIAxes, x, y, fcn_v(x, y));
                    else
                        surf(app.UIAxes, x, y, fcn_v(x, y));
                    end
                    
                catch ME
                    msgbox("Please double-check the formula...");
                    disp(ME.message);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MatrixInputLabel = uilabel(app.LeftPanel);
            app.MatrixInputLabel.Text = 'Matrix Input';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'text');
            app.StartEditField.Value = '-3';
            app.StepEditFieldLabel = uilabel(app.LeftPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.LeftPanel, 'text');
            app.StepEditField.Value = '0.2';
            app.EndEditFieldLabel = uilabel(app.LeftPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.LeftPanel, 'text');
            app.EndEditField.Value = '3';
            app.FcnEditFieldLabel = uilabel(app.LeftPanel);
            app.FcnEditFieldLabel.Text = 'Fcn';
            app.FcnEditField = uieditfield(app.LeftPanel, 'text');
            app.FcnEditField.Value = 'x.^2 .* y.^3';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ContourCheckBox = uicheckbox(app.LeftPanel);
            app.ContourCheckBox.Text = 'Contour?';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graph_3d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end